classdef interfaceDesign < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        DropDown               matlab.ui.control.DropDown
        DropDownLabel          matlab.ui.control.Label
        StartexperimentButton  matlab.ui.control.Button
        IDTextArea             matlab.ui.control.TextArea
        PretestCheckBox        matlab.ui.control.CheckBox
        IDTextAreaLabel        matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function StartexperimentButton_2Pushed(app, event)
            
        end

        % Value changed function: IDTextArea
        function IDTextAreaValueChanged(app, event)

            
        end

        % Callback function
        function SessionnSpinner_2ValueChanged(app, event)

            
        end

        % Value changed function: PretestCheckBox
        function PretestCheckBoxValueChanged(app, event)
            
            
        end

        % Button pushed function: StartexperimentButton
        function StartexperimentButtonPushed(app, event)
            pretest = app.PretestCheckBox.Value;
            ratio = app.DropDown.Value;
            if pretest == false
                cd 'C:\Users\AOE\Desktop\session_2_dots_test\exp'
                pretest=0;
                subID = char(app.IDTextArea.Value);
                
                cd inputs\
                createInputs_SAT_Color_v2(subID,pretest,ratio);
                cd ..
                run_SAT_ColorPenalty(subID,1,pretest);
                %for i = 1:9
                 %   run_SAT_ColorPenalty(subID,i);
                %end
            end
            if pretest
                cd 'C:\Users\AOE\Desktop\session_2_dots_test\exp'
                pretest=1;
                subID = char(app.IDTextArea.Value);
                %Sratio= char(app.DropDown.Value);
                cd inputs\
                createInputs_SAT_Color_v2(subID,pretest,ratio);
                cd ..
                %run_SAT_ColorPenalty(subID,2,pretest);
                for i = 1:9
                    run_SAT_ColorPenalty(subID,i,pretest);
                end
                %run_SAT_ColorPenalty(subID,9,pretest);
            end

        end

        % Drop down opening function: DropDown
        function DropDownOpening(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.IDTextAreaLabel = uilabel(app.UIFigure);
            app.IDTextAreaLabel.Text = 'ID:';
            app.PretestCheckBox = uicheckbox(app.UIFigure);
            app.PretestCheckBox.ValueChangedFcn = createCallbackFcn(app, @PretestCheckBoxValueChanged, true);
            app.PretestCheckBox.Text = 'Pretest';
            app.IDTextArea = uitextarea(app.UIFigure);
            app.IDTextArea.ValueChangedFcn = createCallbackFcn(app, @IDTextAreaValueChanged, true);
            app.IDTextArea.Tag = 'ID';
            app.StartexperimentButton = uibutton(app.UIFigure, 'push');
            app.StartexperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StartexperimentButtonPushed, true);
            app.StartexperimentButton.Text = 'Start experiment';
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'[60]', '[50]', '[40]'};
            app.DropDown.DropDownOpeningFcn = createCallbackFcn(app, @DropDownOpening, true);
            app.DropDown.Value = '[60]';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfaceDesign

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end