classdef interfaceDesign < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        StartexperimentButton   matlab.ui.control.Button
        IDTextArea              matlab.ui.control.TextArea
        IDTextAreaLabel         matlab.ui.control.Label
        SessionnSpinner_2       matlab.ui.control.Spinner
        SessionnSpinner_2Label  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function StartexperimentButton_2Pushed(app, event)

        end

        % Value changed function: IDTextArea
        function IDTextAreaValueChanged(app, event)
            subID = app.IDTextArea.Value;
            
        end

        % Value changed function: SessionnSpinner_2
        function SessionnSpinner_2ValueChanged(app, event)
            blockNo = app.SessionnSpinner_2.Value;
            
        end

        % Button pushed function: StartexperimentButton
        function StartexperimentButtonPushed(app, event)
            cd 'C:\Users\porte\Desktop\swc-python\time is money\session_1_dots_pretest\exp'
            pretest=1;
            subID = char(app.IDTextArea.Value);
            blockNo = app.SessionnSpinner_2.Value;
            
            cd inputs\
            createInputs_SAT_Color_v2(subID,pretest);
            cd ..
            run_SAT_ColorPenalty(subID,blockNo,pretest);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SessionnSpinner_2Label = uilabel(app.UIFigure);
            app.SessionnSpinner_2Label.Text = 'Session n°';
            app.SessionnSpinner_2 = uispinner(app.UIFigure);
            app.SessionnSpinner_2.ValueChangedFcn = createCallbackFcn(app, @SessionnSpinner_2ValueChanged, true);
            app.SessionnSpinner_2.Tag = 'session';
            app.IDTextAreaLabel = uilabel(app.UIFigure);
            app.IDTextAreaLabel.Text = 'ID:';
            app.IDTextArea = uitextarea(app.UIFigure);
            app.IDTextArea.ValueChangedFcn = createCallbackFcn(app, @IDTextAreaValueChanged, true);
            app.IDTextArea.Tag = 'ID';
            app.StartexperimentButton = uibutton(app.UIFigure, 'push');
            app.StartexperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StartexperimentButtonPushed, true);
            app.StartexperimentButton.Text = 'Start experiment';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfaceDesign

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end